classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        reverbPedal           matlab.ui.control.Image
        reverbLampa           matlab.ui.control.Lamp
        reverbKnapp           matlab.ui.control.Button
        reverbLabel           matlab.ui.control.Label
        reverbWetnessLabel    matlab.ui.control.Label
        reverbMixLabel        matlab.ui.control.Label
        reverbDecayKnob       matlab.ui.control.Knob
        reverbWetnessKnob     matlab.ui.control.Knob
        reverbMixKnob         matlab.ui.control.Knob
        reverbDecayLabel      matlab.ui.control.Label
        distortionPedal       matlab.ui.control.Image
        delayPedal            matlab.ui.control.Image
        distortionLabel       matlab.ui.control.Label
        distortionButton      matlab.ui.control.Button
        delayButton           matlab.ui.control.Button
        distortionDesign      matlab.ui.control.Label
        distortionLamp        matlab.ui.control.Lamp
        Label                 matlab.ui.control.Label
        distortionKnob        matlab.ui.control.Knob
        distortionDriveLabel  matlab.ui.control.Label
        delayLabel            matlab.ui.control.Label
        delayDesign           matlab.ui.control.Label
        delayLamp             matlab.ui.control.Lamp
        delayMixKnob          matlab.ui.control.Knob
        speakerDesignBrown    matlab.ui.control.Label
        speakerLabel          matlab.ui.control.Label
        speakerVolumeKnob     matlab.ui.control.Knob
        speakerLamp           matlab.ui.control.Lamp
        speakerSwitch         matlab.ui.control.RockerSwitch
        speakerVolumeLabel    matlab.ui.control.Label
        speakerOnOffLabel     matlab.ui.control.Label
        delayTimeKnob         matlab.ui.control.Knob
        delayDecayKnob        matlab.ui.control.Knob
        delayTimeLabel        matlab.ui.control.Label
        delayMixLabel         matlab.ui.control.Label
        DecayLabel_2          matlab.ui.control.Label
    end

    
    properties (Access = public)
    fileReader;             % Read in file
    deviceReader;           % Read in input from Guitar
    deviceWriter;           % Output audio to soundcard

    reverb;                 % Global variable for Reverb
    echo;                   % Global variable for Echo
    
    speakerVolume = 0.1;    % Speaker volume
    
    reverbWetDryMix = 0     % [0, 1] Wet to dry signal ratio
    reverbDecayFactor = 0.4 % [0, 1] Reverb decay factor
    reverbDiffusion = 0.4   % [0, 1] Reverb Diffusion/feedback
    
    kDist=0;                % Distortion multiplier
    distValue = 0;
    
    delayWetDryMix = 0;     % [0, 1] Wet to dry signal ratio
    delayDelayLevel = 0.35; % [0, 1] Base delay in seconds
    delayDecayFactor= 0.25; % [0, 0.5] Feedback gain 

    reverbSwitch = 0;       % [0 or 1] Reverbswitch
    distortionSwitch = 0;   % [0 or 1] Distswitch
    delaySwitch = 0;        % [0 or 1] Delayswitch
    speakerBreaker = 0;     % [0 or 1] Speakerswitch
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %app.fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
        
        app.deviceReader = audioDeviceReader('SamplesPerFrame',120,'BitDepth', '16-bit integer','Device', 'Onyx Blackjack');
        app.deviceWriter = audioDeviceWriter('SampleRate',app.deviceReader.SampleRate,'SupportVariableSizeInput',true,'BufferSize', 120);

        %Built-In Reverb
        app.reverb = reverberator('WetDryMix', app.reverbWetDryMix,'DecayFactor', app.reverbDecayFactor, 'Diffusion', app.reverbDiffusion);
        %Built-In Echo
        app.echo = audioexample.Echo('Delay',app.delayDelayLevel,'FeedbackLevel',app.delayDecayFactor,'WetDryMix',app.delayWetDryMix);
        end

        % Value changed function: reverbDecayKnob
        function reverbDecayKnobValueChanged(app, event)
            %Decay Knob
            reverbDecayValue = event.Value;
            app.reverbDecayFactor = reverbDecayValue;
            if app.reverbSwitch == 1
            app.reverb = reverberator('WetDryMix', app.reverbWetDryMix,'DecayFactor', app.reverbDecayFactor, 'Diffusion', app.reverbDiffusion);
            end
        end

        % Value changed function: reverbMixKnob
        function reverbMixKnobValueChanged(app, event)
            %Reverb Mixknob
            reverbMixValue = event.Value;
            app.reverbWetDryMix = reverbMixValue;
            if app.reverbSwitch == 1
            app.reverb = reverberator('WetDryMix', app.reverbWetDryMix,'DecayFactor', app.reverbDecayFactor, 'Diffusion', app.reverbDiffusion);
            end
        end

        % Button pushed function: reverbKnapp
        function reverbKnappButtonPushed(app, event)
            %Reverb av/på
            if app.reverbSwitch == 0
                app.reverbLampa.Color = [0.89,0.12,0.12];
                app.reverbSwitch = 1;
                app.reverb = reverberator('WetDryMix', app.reverbWetDryMix,'DecayFactor', app.reverbDecayFactor, 'Diffusion', app.reverbDiffusion);
            else
                app.reverb = reverberator('WetDryMix', 0, 'DecayFactor', 1,'Diffusion', 0.5);
                app.reverbLampa.Color = [0.37,0.05,0.05];
                app.reverbSwitch = 0;
            end
        end

        % Value changed function: reverbWetnessKnob
        function reverbWetnessKnobValueChanged(app, event)
            %Reverb Diffusion
            wetValue = event.Value;
            app.reverbDiffusion = wetValue;
            if app.reverbSwitch == 1
            app.reverb = reverberator('WetDryMix', app.reverbWetDryMix,'DecayFactor', app.reverbDecayFactor, 'Diffusion', app.reverbDiffusion);
            end
        end

        % Button pushed function: distortionButton
        function distortionButtonPushed(app, event)
            %Dist av/på
            if app.distortionSwitch == 0
                app.distortionLamp.Color = [0.89,0.12,0.12];
                app.distortionSwitch = 1;
                app.kDist = 2*app.distValue/(1-app.distValue);
            else
                app.kDist = 0;
                app.distortionLamp.Color = [0.37,0.05,0.05];
                app.distortionSwitch = 0;
            end
        end

        % Button pushed function: delayButton
        function delayButtonPushed(app, event)
            %Delay av/på
            if app.delaySwitch == 0
                app.delayLamp.Color = [0.89,0.12,0.12];
                app.delaySwitch = 1;
                app.echo = audioexample.Echo('Delay',app.delayDelayLevel,'FeedbackLevel',app.delayDecayFactor,'WetDryMix',app.delayWetDryMix);
            else
                app.echo = audioexample.Echo('Delay',0,'FeedbackLevel',0,'WetDryMix',0);
                app.delayLamp.Color = [0.37,0.05,0.05];
                app.delaySwitch = 0;
            end
        end

        % Value changed function: speakerSwitch
        function speakerSwitchValueChanged(app, event)
        %Amp av/på        
        if app.speakerBreaker == 0
                app.speakerLamp.Color = [0.89,0.12,0.12];
                app.speakerBreaker = 1;
         tic
         while toc < 2000 %~isDone(app.deviceReader)
            audio = app.deviceReader();
            drawnow limitrate
            if app.speakerBreaker == 0
                break
            end
            audioWithDist = (1+app.kDist)*(audio)./(1+app.kDist*abs(audio));
            audioWithDelay = app.echo(audioWithDist);
            audioWithReverb = app.speakerVolume.*app.reverb(audioWithDelay);
            app.deviceWriter(audioWithReverb);
        end
        release(app.deviceReader);          
            else 
                app.speakerLamp.Color = [0.37,0.05,0.05]; 
                app.speakerBreaker = 0;
            end
            
        end

        % Value changing function: distortionKnob
        function distortionKnobValueChanging(app, event)
            app.distValue = event.Value;
            if app.distortionSwitch == 1
            app.kDist = 2*app.distValue/(1-app.distValue);
            end
        end

        % Value changing function: delayTimeKnob
        function delayTimeKnobValueChanging(app, event)
            changingValue = event.Value;
            app.delayDelayLevel = changingValue;
            if app.delaySwitch == 1
            app.echo = audioexample.Echo('Delay',app.delayDelayLevel,'FeedbackLevel',app.delayDecayFactor,'WetDryMix',app.delayWetDryMix);
            end
        end

        % Value changing function: delayMixKnob
        function delayMixKnobValueChanging(app, event)
            changingValue = event.Value;
            app.delayWetDryMix = changingValue;
            if app.delaySwitch == 1
            app.echo = audioexample.Echo('Delay',app.delayDelayLevel,'FeedbackLevel',app.delayDecayFactor,'WetDryMix',app.delayWetDryMix);
            end
        end

        % Value changing function: delayDecayKnob
        function delayDecayKnobValueChanging(app, event)
            changingValue = event.Value;
            app.delayDecayFactor = changingValue;
            if app.delaySwitch == 1
            app.echo = audioexample.Echo('Delay',app.delayDelayLevel,'FeedbackLevel',app.delayDecayFactor,'WetDryMix',app.delayWetDryMix);
            end
        end

        % Value changing function: speakerVolumeKnob
        function speakerVolumeKnobValueChanging(app, event)
            app.speakerVolume = event.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.reverbPedal = uiimage(app.UIFigure);
            app.reverbPedal.ImageSource = 'reverb.png';
            app.reverbLampa = uilamp(app.UIFigure);
            app.reverbKnapp = uibutton(app.UIFigure, 'push');
            app.reverbKnapp.ButtonPushedFcn = createCallbackFcn(app, @reverbKnappButtonPushed, true);
            app.reverbKnapp.Text = '';
            app.reverbLabel = uilabel(app.UIFigure);
            app.reverbLabel.Text = 'REVERB';
            app.reverbWetnessLabel = uilabel(app.UIFigure);
            app.reverbWetnessLabel.Text = {'WETNESS'; ''};
            app.reverbMixLabel = uilabel(app.UIFigure);
            app.reverbMixLabel.Text = {'MIX'; ''};
            app.reverbDecayKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbDecayKnob.Limits = [0 1];
            app.reverbDecayKnob.MajorTicks = [0 24 36 42 54 60 78 84 90 100];
            app.reverbDecayKnob.ValueChangedFcn = createCallbackFcn(app, @reverbDecayKnobValueChanged, true);
            app.reverbDecayKnob.MinorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.reverbDecayKnob.Value = 0.4;
            app.reverbWetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbWetnessKnob.Limits = [0 1];
            app.reverbWetnessKnob.ValueChangedFcn = createCallbackFcn(app, @reverbWetnessKnobValueChanged, true);
            app.reverbWetnessKnob.Value = 0.4;
            app.reverbMixKnob = uiknob(app.UIFigure, 'continuous');
            app.reverbMixKnob.Limits = [0 1];
            app.reverbMixKnob.ValueChangedFcn = createCallbackFcn(app, @reverbMixKnobValueChanged, true);
            app.reverbDecayLabel = uilabel(app.UIFigure);
            app.reverbDecayLabel.Text = {'Decay'; ''};
            app.distortionPedal = uiimage(app.UIFigure);
            app.distortionPedal.ImageSource = 'dist.png';
            app.delayPedal = uiimage(app.UIFigure);
            app.delayPedal.ImageSource = 'delay.png';
            app.distortionLabel = uilabel(app.UIFigure);
            app.distortionLabel.Text = 'DISTORTION';
            app.distortionButton = uibutton(app.UIFigure, 'push');
            app.distortionButton.ButtonPushedFcn = createCallbackFcn(app, @distortionButtonPushed, true);
            app.distortionButton.Text = '';
            app.delayButton = uibutton(app.UIFigure, 'push');
            app.delayButton.ButtonPushedFcn = createCallbackFcn(app, @delayButtonPushed, true);
            app.delayButton.Text = '';
            app.distortionDesign = uilabel(app.UIFigure);
            app.distortionDesign.Text = '';
            app.distortionLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.distortionKnob = uiknob(app.UIFigure, 'continuous');
            app.distortionKnob.Limits = [0 0.99];
            app.distortionKnob.MajorTicks = [0 30 70 100];
            app.distortionKnob.MajorTickLabels = {'0', '30', '70', '100'};
            app.distortionKnob.ValueChangingFcn = createCallbackFcn(app, @distortionKnobValueChanging, true);
            app.distortionDriveLabel = uilabel(app.UIFigure);
            app.distortionDriveLabel.Text = {'Drive'; ''};
            app.delayLabel = uilabel(app.UIFigure);
            app.delayLabel.Text = 'DELAY';
            app.delayDesign = uilabel(app.UIFigure);
            app.delayDesign.Text = '';
            app.delayLamp = uilamp(app.UIFigure);
            app.delayMixKnob = uiknob(app.UIFigure, 'continuous');
            app.delayMixKnob.Limits = [0 1];
            app.delayMixKnob.ValueChangingFcn = createCallbackFcn(app, @delayMixKnobValueChanging, true);
            app.speakerDesignBrown = uilabel(app.UIFigure);
            app.speakerDesignBrown.Text = '';
            app.speakerLabel = uilabel(app.UIFigure);
            app.speakerLabel.Text = {'Mysigt'; ''};
            app.speakerVolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.speakerVolumeKnob.Limits = [0.001 5];
            app.speakerVolumeKnob.ValueChangingFcn = createCallbackFcn(app, @speakerVolumeKnobValueChanging, true);
            app.speakerVolumeKnob.Value = 0.5;
            app.speakerLamp = uilamp(app.UIFigure);
            app.speakerSwitch = uiswitch(app.UIFigure, 'rocker');
            app.speakerSwitch.ValueChangedFcn = createCallbackFcn(app, @speakerSwitchValueChanged, true);
            app.speakerVolumeLabel = uilabel(app.UIFigure);
            app.speakerVolumeLabel.Text = 'VOLUME';
            app.speakerOnOffLabel = uilabel(app.UIFigure);
            app.speakerOnOffLabel.Text = {'On/Off'; ''};
            app.delayTimeKnob = uiknob(app.UIFigure, 'continuous');
            app.delayTimeKnob.Limits = [0 1];
            app.delayTimeKnob.ValueChangingFcn = createCallbackFcn(app, @delayTimeKnobValueChanging, true);
            app.delayTimeKnob.Value = 0.35;
            app.delayDecayKnob = uiknob(app.UIFigure, 'continuous');
            app.delayDecayKnob.Limits = [0 0.5];
            app.delayDecayKnob.ValueChangingFcn = createCallbackFcn(app, @delayDecayKnobValueChanging, true);
            app.delayDecayKnob.Value = 0.25;
            app.delayTimeLabel = uilabel(app.UIFigure);
            app.delayTimeLabel.Text = 'Time';
            app.delayMixLabel = uilabel(app.UIFigure);
            app.delayMixLabel.Text = 'Mix';
            app.DecayLabel_2 = uilabel(app.UIFigure);
            app.DecayLabel_2.Text = 'Decay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end